classdef ElementCreationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ElementCreatorUIFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SourceTab                       matlab.ui.container.Tab
        WavelengthEditField             matlab.ui.control.NumericEditField
        WavelengthEditFieldLabel        matlab.ui.control.Label
        SourceCreateButton              matlab.ui.control.Button
        LensTab                         matlab.ui.container.Tab
        LensDistanceFromLastElementEditField  matlab.ui.control.NumericEditField
        DistanceFromLastElementEditFieldLabel  matlab.ui.control.Label
        ApertureDiameterEditField       matlab.ui.control.NumericEditField
        ApertureDiameterEditFieldLabel  matlab.ui.control.Label
        FocalLengthEditField            matlab.ui.control.NumericEditField
        FocalLengthEditFieldLabel       matlab.ui.control.Label
        LensCreateButton                matlab.ui.control.Button
        DetectorTab                     matlab.ui.container.Tab
        DetectorDistanceFromLastElementEditField  matlab.ui.control.NumericEditField
        DistanceFromLastElementEditField_2Label  matlab.ui.control.Label
        PixelPitchEditField             matlab.ui.control.NumericEditField
        PixelPitchEditFieldLabel        matlab.ui.control.Label
        ResolutionEditField             matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel        matlab.ui.control.Label
        DetectorCreateButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        data = struct();
    end
    
    methods (Access = private)
        
        function terminate(app)
            uiresume(app.ElementCreatorUIFigure);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(0, 'ScreenSize');
            figWidth = app.ElementCreatorUIFigure.Position(3);
            figHeight = app.ElementCreatorUIFigure.Position(4);
            app.ElementCreatorUIFigure.Position(1) = (screenSize(3) - figWidth) / 2;
            app.ElementCreatorUIFigure.Position(2) = (screenSize(4) - figHeight) / 2;
        end

        % Button pushed function: SourceCreateButton
        function SourceCreateButtonPushed(app, event)
            app.data.elementType = "source";
            app.data.wavelength = app.WavelengthEditField.Value;
            app.terminate();
        end

        % Button pushed function: LensCreateButton
        function LensCreateButtonPushed(app, event)
            app.data.elementType = "lens";
            app.data.dist = app.LensDistanceFromLastElementEditField.Value;
            app.data.focalLength = app.FocalLengthEditField.Value;
            app.data.apertureSize = app.ApertureDiameterEditField.Value;
            app.terminate();
        end

        % Button pushed function: DetectorCreateButton
        function DetectorCreateButtonPushed(app, event)
            app.data.elementType = "detector";
            app.data.dist = app.DetectorDistanceFromLastElementEditField.Value;
            app.data.varargin = {};

            if ~isempty(app.PixelPitchEditField.Value)
                app.data.varargin = [app.data.varargin, {'pixelPitch', app.PixelPitchEditField.Value}];
            end
            if ~isempty(app.ResolutionEditField.Value)
                app.data.varargin = [app.data.varargin, {'resolution', app.ResolutionEditField.Value}];
            end

            app.terminate();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ElementCreatorUIFigure = uifigure('Visible', 'off');
            app.ElementCreatorUIFigure.Name = 'Element Creator';
            app.TabGroup = uitabgroup(app.ElementCreatorUIFigure);
            app.SourceTab = uitab(app.TabGroup);
            app.SourceTab.Title = 'Source';
            app.SourceCreateButton = uibutton(app.SourceTab, 'push');
            app.SourceCreateButton.ButtonPushedFcn = createCallbackFcn(app, @SourceCreateButtonPushed, true);
            app.SourceCreateButton.Text = 'Create';
            app.WavelengthEditFieldLabel = uilabel(app.SourceTab);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.SourceTab, 'numeric');
            app.WavelengthEditField.Value = 6.32e-07;
            app.LensTab = uitab(app.TabGroup);
            app.LensTab.Title = 'Lens';
            app.LensCreateButton = uibutton(app.LensTab, 'push');
            app.LensCreateButton.ButtonPushedFcn = createCallbackFcn(app, @LensCreateButtonPushed, true);
            app.LensCreateButton.Text = 'Create';
            app.FocalLengthEditFieldLabel = uilabel(app.LensTab);
            app.FocalLengthEditFieldLabel.Text = 'Focal Length';
            app.FocalLengthEditField = uieditfield(app.LensTab, 'numeric');
            app.FocalLengthEditField.Value = 0.1;
            app.ApertureDiameterEditFieldLabel = uilabel(app.LensTab);
            app.ApertureDiameterEditFieldLabel.Text = 'Aperture Diameter';
            app.ApertureDiameterEditField = uieditfield(app.LensTab, 'numeric');
            app.ApertureDiameterEditField.Value = 0.001;
            app.DistanceFromLastElementEditFieldLabel = uilabel(app.LensTab);
            app.DistanceFromLastElementEditFieldLabel.Text = 'Distance From Last Element';
            app.LensDistanceFromLastElementEditField = uieditfield(app.LensTab, 'numeric');
            app.LensDistanceFromLastElementEditField.Value = 0.01;
            app.DetectorTab = uitab(app.TabGroup);
            app.DetectorTab.Title = 'Detector';
            app.DetectorCreateButton = uibutton(app.DetectorTab, 'push');
            app.DetectorCreateButton.ButtonPushedFcn = createCallbackFcn(app, @DetectorCreateButtonPushed, true);
            app.DetectorCreateButton.Text = 'Create';
            app.ResolutionEditFieldLabel = uilabel(app.DetectorTab);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.DetectorTab, 'numeric');
            app.ResolutionEditField.AllowEmpty = 'on';
            app.ResolutionEditField.Value = [];
            app.PixelPitchEditFieldLabel = uilabel(app.DetectorTab);
            app.PixelPitchEditFieldLabel.Text = 'Pixel Pitch';
            app.PixelPitchEditField = uieditfield(app.DetectorTab, 'numeric');
            app.PixelPitchEditField.AllowEmpty = 'on';
            app.PixelPitchEditField.Value = [];
            app.DistanceFromLastElementEditField_2Label = uilabel(app.DetectorTab);
            app.DistanceFromLastElementEditField_2Label.Text = 'Distance From Last Element';
            app.DetectorDistanceFromLastElementEditField = uieditfield(app.DetectorTab, 'numeric');
            app.DetectorDistanceFromLastElementEditField.Value = 0.01;
            % Show the figure after all components are created
            app.ElementCreatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElementCreationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ElementCreatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ElementCreatorUIFigure)
        end
    end
end