classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OSFAppUIFigure              matlab.ui.Figure
        EditButton_2                matlab.ui.control.Button
        RemoveButton_2              matlab.ui.control.Button
        AddButton_2                 matlab.ui.control.Button
        EditButton                  matlab.ui.control.Button
        RemoveButton                matlab.ui.control.Button
        PropagateButton             matlab.ui.control.Button
        VisualsListBox              matlab.ui.control.ListBox
        VisualsListBoxLabel         matlab.ui.control.Label
        PaddingRatioEditField       matlab.ui.control.NumericEditField
        PaddingRatioEditFieldLabel  matlab.ui.control.Label
        DimensionsEditField         matlab.ui.control.NumericEditField
        DimensionsEditFieldLabel    matlab.ui.control.Label
        FOVEditField                matlab.ui.control.NumericEditField
        FOVEditFieldLabel           matlab.ui.control.Label
        ResolutionEditFieldLabel_3  matlab.ui.control.Label
        ResolutionEditFieldLabel_2  matlab.ui.control.Label
        ResolutionEditField         matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel    matlab.ui.control.Label
        AddButton                   matlab.ui.control.Button
        ElementsListBox             matlab.ui.control.ListBox
        ElementsListBoxLabel        matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sim
    end
    
    methods (Access = private)
        
        function createElement(app, data)
            switch data.elementType
                case 'source'
                    newElement = app.sim.addSource(data.wavelength);
                case 'lens'
                    newElement = app.sim.addLens(data.dist, data.focalLength, circ=data.apertureSize);
                    app.updateUI();
                case 'detector'
                    newElement = app.sim.addDetector(data.dist);
                    app.updateUI();
                otherwise
                    error("Support for elementType '%s' has not yet been implemented.");
            end

            app.ElementsListBox.Items = [app.ElementsListBox.Items, {newElement.name}];

        end

        function updateUI(app)
            osf.vis.attachData(app.UIAxes, osf.vis.prepData(app.sim, 'default'));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center app window in screen.
            screenSize = get(0, 'ScreenSize');
            figWidth = app.OSFAppUIFigure.Position(3);
            figHeight = app.OSFAppUIFigure.Position(4);
            app.OSFAppUIFigure.Position(1) = (screenSize(3) - figWidth) / 2;
            app.OSFAppUIFigure.Position(2) = (screenSize(4) - figHeight) / 2;
            app.sim = osf.Sim(1e-6, 1e-3);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            elementApp = osf.gui.ElementCreationApp;
            uiwait(elementApp.ElementCreatorUIFigure);
            elementData = elementApp.data;
            delete(elementApp);
            app.createElement(elementData);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OSFAppUIFigure = uifigure('Visible', 'off');
            app.OSFAppUIFigure.Name = 'OSF App';
            app.UIAxes = uiaxes(app.OSFAppUIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ElementsListBoxLabel = uilabel(app.OSFAppUIFigure);
            app.ElementsListBoxLabel.Text = 'Elements';
            app.ElementsListBox = uilistbox(app.OSFAppUIFigure);
            app.ElementsListBox.Items = {};
            app.ElementsListBox.Value = {};
            app.AddButton = uibutton(app.OSFAppUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ResolutionEditFieldLabel = uilabel(app.OSFAppUIFigure);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.OSFAppUIFigure, 'numeric');
            app.ResolutionEditField.Value = 1;
            app.ResolutionEditFieldLabel_2 = uilabel(app.OSFAppUIFigure);
            app.ResolutionEditFieldLabel_2.Text = 'μm';
            app.ResolutionEditFieldLabel_3 = uilabel(app.OSFAppUIFigure);
            app.ResolutionEditFieldLabel_3.Text = 'mm';
            app.FOVEditFieldLabel = uilabel(app.OSFAppUIFigure);
            app.FOVEditFieldLabel.Text = 'FOV';
            app.FOVEditField = uieditfield(app.OSFAppUIFigure, 'numeric');
            app.FOVEditField.Value = 1;
            app.DimensionsEditFieldLabel = uilabel(app.OSFAppUIFigure);
            app.DimensionsEditFieldLabel.Text = 'Dimensions';
            app.DimensionsEditField = uieditfield(app.OSFAppUIFigure, 'numeric');
            app.DimensionsEditField.Value = 2;
            app.PaddingRatioEditFieldLabel = uilabel(app.OSFAppUIFigure);
            app.PaddingRatioEditFieldLabel.Text = 'Padding Ratio';
            app.PaddingRatioEditField = uieditfield(app.OSFAppUIFigure, 'numeric');
            app.PaddingRatioEditField.Value = 2;
            app.VisualsListBoxLabel = uilabel(app.OSFAppUIFigure);
            app.VisualsListBoxLabel.Text = 'Visuals';
            app.VisualsListBox = uilistbox(app.OSFAppUIFigure);
            app.VisualsListBox.Items = {};
            app.VisualsListBox.Value = {};
            app.PropagateButton = uibutton(app.OSFAppUIFigure, 'push');
            app.PropagateButton.Text = 'Propagate';
            app.RemoveButton = uibutton(app.OSFAppUIFigure, 'push');
            app.RemoveButton.Text = 'Remove';
            app.EditButton = uibutton(app.OSFAppUIFigure, 'push');
            app.EditButton.Text = 'Edit';
            app.AddButton_2 = uibutton(app.OSFAppUIFigure, 'push');
            app.AddButton_2.Text = 'Add';
            app.RemoveButton_2 = uibutton(app.OSFAppUIFigure, 'push');
            app.RemoveButton_2.Text = 'Remove';
            app.EditButton_2 = uibutton(app.OSFAppUIFigure, 'push');
            app.EditButton_2.Text = 'Edit';
            % Show the figure after all components are created
            app.OSFAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OSFAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OSFAppUIFigure)
        end
    end
end